classdef app_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KematanganEditField       matlab.ui.control.EditField
        KematanganEditFieldLabel  matlab.ui.control.Label
        DefuzzyEditField          matlab.ui.control.NumericEditField
        DefuzzyEditFieldLabel     matlab.ui.control.Label
        HSVHEditField             matlab.ui.control.NumericEditField
        HSVHEditFieldLabel        matlab.ui.control.Label
        LabaEditField             matlab.ui.control.NumericEditField
        LabaEditFieldLabel        matlab.ui.control.Label
        ImageHSVLabel             matlab.ui.control.Label
        ImageLabLabel             matlab.ui.control.Label
        ImageremovebgLabel        matlab.ui.control.Label
        ImageRGBLabel             matlab.ui.control.Label
        Image_rgb                 matlab.ui.control.Image
        Image_lab                 matlab.ui.control.Image
        Image_hsv                 matlab.ui.control.Image
        ResetButton               matlab.ui.control.Button
        ProcessButton             matlab.ui.control.Button
        BrowseButton              matlab.ui.control.Button
        Image_remove_bg           matlab.ui.control.Image
    end

    
    properties (Access = private)
        I_rgb = ''; % Description
        fis = readfis('mamdani'); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path]=uigetfile({'*.jpg';'*.png';'*.jpeg'}, 'Select file');
            
            if file == 0
              % User clicked the Cancel button.
              return;
            end
            
            image = imread(fullfile(path, file));
            
            if length(image) > 1000
                app.I_rgb = imresize(image,[1000 NaN]);
            else
                app.I_rgb = image;
            end

            app.Image_rgb.ImageSource = app.I_rgb;
            
            drawnow;
            figure(app.UIFigure);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isempty(app.I_rgb)
                return
            end
            
            [~, maskedRGB] = remove_bg_kmeans(app.I_rgb);
            
            I_lab = rgb2lab(maskedRGB);
            I_hsv = rgb2hsv(maskedRGB);
            
            a_mean = mean(nonzeros(I_lab(:,:,2)));
            h_mean = mean(nonzeros(I_hsv(:,:,1)));
            output_fuzzy = evalfis(app.fis, [a_mean h_mean]);
            
            if output_fuzzy < 2.445325676
                kematangan = "Mentah";
            elseif output_fuzzy > 2.445325676 && output_fuzzy < 8.175101665
                kematangan = "Setengah Matang";
            elseif output_fuzzy > 8.175101665
                kematangan = "Matang";
            end
            
            app.HSVHEditField.Value = h_mean;
            app.LabaEditField.Value = a_mean;
            app.DefuzzyEditField.Value = output_fuzzy;
            app.KematanganEditField.Value = kematangan;
            
            app.Image_lab.ImageSource = I_lab;
            app.Image_hsv.ImageSource = I_hsv;
            app.Image_remove_bg.ImageSource = maskedRGB;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Image_lab.ImageSource = '';
            app.Image_hsv.ImageSource = '';
            app.Image_remove_bg.ImageSource = '';
            app.Image_rgb.ImageSource = '';
            app.I_rgb = '';
            
            app.HSVHEditField.Value = 0;
            app.LabaEditField.Value = 0;
            app.DefuzzyEditField.Value = 0;
            app.KematanganEditField.Value = "";
            
            clear;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_remove_bg = uiimage(app.UIFigure);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image_hsv = uiimage(app.UIFigure);
            app.Image_lab = uiimage(app.UIFigure);
            app.Image_rgb = uiimage(app.UIFigure);
            app.ImageRGBLabel = uilabel(app.UIFigure);
            app.ImageRGBLabel.Text = 'Image RGB';
            app.ImageremovebgLabel = uilabel(app.UIFigure);
            app.ImageremovebgLabel.Text = 'Image remove bg';
            app.ImageLabLabel = uilabel(app.UIFigure);
            app.ImageLabLabel.Text = 'Image L*a*b';
            app.ImageHSVLabel = uilabel(app.UIFigure);
            app.ImageHSVLabel.Text = 'Image HSV';
            app.LabaEditFieldLabel = uilabel(app.UIFigure);
            app.LabaEditFieldLabel.Text = 'L*a*b : a';
            app.LabaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LabaEditField.Editable = 'off';
            app.HSVHEditFieldLabel = uilabel(app.UIFigure);
            app.HSVHEditFieldLabel.Text = 'HSV : H';
            app.HSVHEditField = uieditfield(app.UIFigure, 'numeric');
            app.HSVHEditField.Editable = 'off';
            app.DefuzzyEditFieldLabel = uilabel(app.UIFigure);
            app.DefuzzyEditFieldLabel.Text = 'Defuzzy';
            app.DefuzzyEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefuzzyEditField.Editable = 'off';
            app.KematanganEditFieldLabel = uilabel(app.UIFigure);
            app.KematanganEditFieldLabel.Text = 'Kematangan';
            app.KematanganEditField = uieditfield(app.UIFigure, 'text');
            app.KematanganEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end