classdef tes_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HasilDeteksiEditField       matlab.ui.control.EditField
        HasilDeteksiEditFieldLabel  matlab.ui.control.Label
        NilaiDefuzzyEditField       matlab.ui.control.EditField
        NilaiDefuzzyEditFieldLabel  matlab.ui.control.Label
        NilaiLab_aEditField         matlab.ui.control.EditField
        NilaiLab_aEditFieldLabel    matlab.ui.control.Label
        NilaiHSV_HEditField         matlab.ui.control.EditField
        NilaiHSV_HEditFieldLabel    matlab.ui.control.Label
        ProcessButton               matlab.ui.control.Button
        BrowseFilesButton           matlab.ui.control.Button
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFilesButton
        function BrowseFilesButtonPushed(app, event)
            global a;
            [FileName, FilePath]= uigetfile({'*.jpg';'*.bnp';'*.gif';'*.tiff'}, 'Select a file');
            if isnumeric(FileName); return; end % User not select file
            a = imread(fullfile(FilePath, FileName));
            imshow(a, 'Parent' , app.UIAxes);
            drawnow;
            figure(app.UIFigure);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global a;
            b = a;
            
            [~,rgb] = remove_bg_kmeans(b);
            imshow(rgb,'Parent',app.UIAxes_2);
            drawnow;
            
            to_lab = rgb2lab(rgb);
            lab_a = to_lab(:,:,2);
            imshow(lab_a,'Parent',app.UIAxes_3);
            drawnow;
            
            to_hsv = rgb2hsv(rgb);
            hsv_h = to_hsv(:,:,1);
            imshow(hsv_h,'Parent',app.UIAxes_4);
            drawnow;
            
            mean_h = mean(nonzeros(hsv_h));
            app.NilaiHSV_HEditField.Value = string(mean_h);
            drawnow;
            
            mean_a = mean(nonzeros(lab_a));
            app.NilaiLab_aEditField.Value = string(mean_a);
            drawnow;
            
            defuzzy = evalfis(fis, [mean_h mean_a]);
            app.NilaiDefuzzyEditField.Value = string(defuzzy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Citra Asli')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Citra Background Removed')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Citra Lab a')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Citra HSV H')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.BrowseFilesButton = uibutton(app.UIFigure, 'push');
            app.BrowseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilesButtonPushed, true);
            app.BrowseFilesButton.Text = 'Browse Files';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.NilaiHSV_HEditFieldLabel = uilabel(app.UIFigure);
            app.NilaiHSV_HEditFieldLabel.Text = 'Nilai HSV_H';
            app.NilaiHSV_HEditField = uieditfield(app.UIFigure, 'text');
            app.NilaiLab_aEditFieldLabel = uilabel(app.UIFigure);
            app.NilaiLab_aEditFieldLabel.Text = 'Nilai Lab_a';
            app.NilaiLab_aEditField = uieditfield(app.UIFigure, 'text');
            app.NilaiDefuzzyEditFieldLabel = uilabel(app.UIFigure);
            app.NilaiDefuzzyEditFieldLabel.Text = 'Nilai Defuzzy';
            app.NilaiDefuzzyEditField = uieditfield(app.UIFigure, 'text');
            app.HasilDeteksiEditFieldLabel = uilabel(app.UIFigure);
            app.HasilDeteksiEditFieldLabel.Text = 'Hasil Deteksi';
            app.HasilDeteksiEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tes_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end